classdef helmholtz_deviation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DmmSliderLabel          matlab.ui.control.Label
        DmmSlider               matlab.ui.control.Slider
        ironcoreCheckBox        matlab.ui.control.CheckBox
        nSliderLabel            matlab.ui.control.Label
        nSlider                 matlab.ui.control.Slider
        ISliderLabel            matlab.ui.control.Label
        ISlider                 matlab.ui.control.Slider
        lockaxesCheckBox        matlab.ui.control.CheckBox
        RmmSliderLabel          matlab.ui.control.Label
        RmmSlider               matlab.ui.control.Slider
        RmmEditFieldLabel       matlab.ui.control.Label
        RmmEditField            matlab.ui.control.EditField
        nTurnsEditFieldLabel    matlab.ui.control.Label
        nTurnsEditField         matlab.ui.control.EditField
        DmmEditFieldLabel       matlab.ui.control.Label
        DmmEditField            matlab.ui.control.EditField
        IcurrentEditFieldLabel  matlab.ui.control.Label
        IcurrentEditField       matlab.ui.control.EditField
        RefreshPlotButton       matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function plotme(app)
            nTurns = app.nSlider.Value;
            separationDist = app.DmmSlider.Value/1000;
            I = app.ISlider.Value;
            R = app.RmmSlider.Value/1000;
            ironCore = app.ironcoreCheckBox.Value;
            
            if ironCore
                mu0 = 6.3e-3; % permeability of iron
            else
                mu0 = 4 * pi * 10^-7; % permeability of air
            end
            
            % separationDist = linspace(0.025,.5,50); % cycle through separtion distances
            
            z_axial = linspace(-.2,.2,1000);
            zplot = z_axial*1000;
            
            B = 0.5 * mu0 * nTurns * I * R^2 * ( ...
                (R^2 + (z_axial + separationDist/2).^2).^(-3/2) + ... % left coil
                (R^2+(z_axial-separationDist/2).^2).^(-3/2)... % right coil
                ); 
            
            B_left = 0.5 * mu0 * nTurns * I * R^2 * ( ...
                (R^2 + (z_axial + separationDist/2).^2).^(-3/2));
            
            B_right = 0.5 * mu0 * nTurns * I * R^2 * ( ...
                (R^2+(z_axial-separationDist/2).^2).^(-3/2));
            
            %%
            if app.lockaxesCheckBox.Value
                tempAx = get(app.UIAxes, 'ylim');
            end
            coilPair = plot(app.UIAxes, zplot,B, 'linewidth', 3, 'color', 'black');
            hold(app.UIAxes, 'on')
            leftCoil = plot(app.UIAxes, zplot,B_left, 'linewidth', 2, 'color', 'blue');
            rightCoil = plot(app.UIAxes, zplot,B_right, 'linewidth', 2, 'color', 'red');
            hold(app.UIAxes, 'off')
            
            grid(app.UIAxes, 'on')
            if app.lockaxesCheckBox.Value
                set(app.UIAxes, 'ylim', tempAx);
            else
                set(app.UIAxes, 'ylimmode', 'auto');
            end
            
            % set edit fields
            app.DmmEditField.Value = num2str(app.DmmSlider.Value)
            app.RmmEditField.Value = num2str(app.RmmSlider.Value)
            app.nTurnsEditField.Value = num2str(app.nSlider.Value)
            app.IcurrentEditField.Value = num2str(app.ISlider.Value)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.plotme();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {500, 500};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {322, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: DmmSlider, ISlider, RmmSlider, 
        % ironcoreCheckBox, lockaxesCheckBox, nSlider
        function nSliderValueChanged(app, event)
            app.plotme();
        end

        % Button pushed function: RefreshPlotButton
        function RefreshPlotButtonPushed(app, event)
            try
                app.nSlider.Value = str2double(app.nTurnsEditField.Value);
                app.DmmSlider.Value = str2double(app.DmmEditField.Value);
                app.ISlider.Value = str2double(app.IcurrentEditField.Value);
                app.RmmSlider.Value = str2double(app.RmmEditField.Value);
            catch ME
                
            end

            app.plotme();

        end

        % Value changed function: RmmEditField
        function RmmEditFieldValueChanged(app, event)
            try
                app.RmmSlider.Value = str2double(app.RmmEditField.Value);
            catch ME
                
            end
            app.plotme()
        end

        % Value changed function: nTurnsEditField
        function nTurnsEditFieldValueChanged(app, event)
            try
                app.nSlider.Value = str2double(app.nTurnsEditField.Value);
            catch ME
                
            end
            app.plotme()
        end

        % Value changed function: DmmEditField
        function DmmEditFieldValueChanged(app, event)
            try
                app.DmmSlider.Value = str2double(app.DmmEditField.Value);
            catch ME
                
            end
            app.plotme()
        end

        % Value changed function: IcurrentEditField
        function IcurrentEditFieldValueChanged(app, event)
            try 
                app.ISlider.Value = str2double(app.IcurrentEditField.Value);
            catch ME
                
            end
            app.plotme();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DmmSliderLabel = uilabel(app.LeftPanel);
            app.DmmSliderLabel.Text = 'D (mm)';
            app.DmmSlider = uislider(app.LeftPanel);
            app.DmmSlider.Limits = [0 400];
            app.DmmSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.DmmSlider.Value = 0.01;
            app.ironcoreCheckBox = uicheckbox(app.LeftPanel);
            app.ironcoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.ironcoreCheckBox.Text = 'iron core';
            app.nSliderLabel = uilabel(app.LeftPanel);
            app.nSliderLabel.Text = 'n';
            app.nSlider = uislider(app.LeftPanel);
            app.nSlider.Limits = [1 200];
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.nSlider.Value = 1;
            app.ISliderLabel = uilabel(app.LeftPanel);
            app.ISliderLabel.Text = 'I';
            app.ISlider = uislider(app.LeftPanel);
            app.ISlider.Limits = [0 10];
            app.ISlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.ISlider.Value = 1;
            app.lockaxesCheckBox = uicheckbox(app.LeftPanel);
            app.lockaxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.lockaxesCheckBox.Text = 'lock axes';
            app.RmmSliderLabel = uilabel(app.LeftPanel);
            app.RmmSliderLabel.Text = 'R (mm)';
            app.RmmSlider = uislider(app.LeftPanel);
            app.RmmSlider.Limits = [10 200];
            app.RmmSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.RmmSlider.Value = 100;
            app.RmmEditFieldLabel = uilabel(app.LeftPanel);
            app.RmmEditFieldLabel.Text = 'R (mm)';
            app.RmmEditField = uieditfield(app.LeftPanel, 'text');
            app.RmmEditField.ValueChangedFcn = createCallbackFcn(app, @RmmEditFieldValueChanged, true);
            app.RmmEditField.Value = '100';
            app.nTurnsEditFieldLabel = uilabel(app.LeftPanel);
            app.nTurnsEditFieldLabel.Text = 'n, Turns';
            app.nTurnsEditField = uieditfield(app.LeftPanel, 'text');
            app.nTurnsEditField.ValueChangedFcn = createCallbackFcn(app, @nTurnsEditFieldValueChanged, true);
            app.nTurnsEditField.Value = '1';
            app.DmmEditFieldLabel = uilabel(app.LeftPanel);
            app.DmmEditFieldLabel.Text = 'D (mm)';
            app.DmmEditField = uieditfield(app.LeftPanel, 'text');
            app.DmmEditField.ValueChangedFcn = createCallbackFcn(app, @DmmEditFieldValueChanged, true);
            app.DmmEditField.Value = '0';
            app.IcurrentEditFieldLabel = uilabel(app.LeftPanel);
            app.IcurrentEditFieldLabel.Text = 'I, current';
            app.IcurrentEditField = uieditfield(app.LeftPanel, 'text');
            app.IcurrentEditField.ValueChangedFcn = createCallbackFcn(app, @IcurrentEditFieldValueChanged, true);
            app.IcurrentEditField.Value = '1';
            app.RefreshPlotButton = uibutton(app.LeftPanel, 'push');
            app.RefreshPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPlotButtonPushed, true);
            app.RefreshPlotButton.Text = 'Refresh Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Magnetic Field distribution')
            xlabel(app.UIAxes, 'Axial distance (mm)')
            ylabel(app.UIAxes, 'B (Teslas)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = helmholtz_deviation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end